classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        Panel_2                matlab.ui.container.Panel
        Image1                 matlab.ui.control.UIAxes
        Panel_3                matlab.ui.container.Panel
        Image2                 matlab.ui.control.UIAxes
        AdjustGraphicsPanel    matlab.ui.container.Panel
        HSLRGBPanel            matlab.ui.container.Panel
        ButtonGroup            matlab.ui.container.ButtonGroup
        HSLButton              matlab.ui.control.RadioButton
        RGBButton              matlab.ui.control.RadioButton
        SaturationSliderLabel  matlab.ui.control.Label
        SaturationSlider       matlab.ui.control.Slider
        LightSliderLabel       matlab.ui.control.Label
        LightSlider            matlab.ui.control.Slider
        HueSliderLabel         matlab.ui.control.Label
        HueSlider              matlab.ui.control.Slider
        RedSliderLabel         matlab.ui.control.Label
        RedSlider              matlab.ui.control.Slider
        GreenSliderLabel       matlab.ui.control.Label
        GreenSlider            matlab.ui.control.Slider
        BlueSliderLabel        matlab.ui.control.Label
        BlueSlider             matlab.ui.control.Slider
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, func)
            imshow('peppers.png','Parent',app.Image1);
            imshow('corn.tif','Parent',app.Image2);
            app.RedSlider.Enable = 'off';
            app.GreenSlider.Enable = 'off';
            app.BlueSlider.Enable = 'off';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if selectedButton == app.HSLButton
                app.SaturationSlider.Enable = 'on';
                app.LightSlider.Enable = 'on';
                app.HueSlider.Enable = 'on';
                app.RedSlider.Enable = 'off';
                app.GreenSlider.Enable = 'off';
                app.BlueSlider.Enable = 'off';
            else
                app.RedSlider.Enable = 'on';
                app.GreenSlider.Enable = 'on';
                app.BlueSlider.Enable = 'on';
                app.SaturationSlider.Enable = 'off';
                app.LightSlider.Enable = 'off';
                app.HueSlider.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel_2 = uipanel(app.Panel);
            app.Image1 = uiaxes(app.Panel_2);
            title(app.Image1, 'Original Image')
            xlabel(app.Image1, '')
            ylabel(app.Image1, '')
            app.Panel_3 = uipanel(app.Panel);
            app.Image2 = uiaxes(app.Panel_3);
            title(app.Image2, 'Edited Image')
            xlabel(app.Image2, '')
            ylabel(app.Image2, '')
            app.AdjustGraphicsPanel = uipanel(app.UIFigure);
            app.AdjustGraphicsPanel.Title = 'Adjust Graphics';
            app.HSLRGBPanel = uipanel(app.AdjustGraphicsPanel);
            app.HSLRGBPanel.Title = 'HSL/RGB';
            app.ButtonGroup = uibuttongroup(app.HSLRGBPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.HSLButton = uiradiobutton(app.ButtonGroup);
            app.HSLButton.Text = 'HSL';
            app.HSLButton.Value = true;
            app.RGBButton = uiradiobutton(app.ButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.SaturationSliderLabel = uilabel(app.HSLRGBPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.HSLRGBPanel);
            app.LightSliderLabel = uilabel(app.HSLRGBPanel);
            app.LightSliderLabel.Text = {'Light'; ''};
            app.LightSlider = uislider(app.HSLRGBPanel);
            app.HueSliderLabel = uilabel(app.HSLRGBPanel);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.HSLRGBPanel);
            app.RedSliderLabel = uilabel(app.HSLRGBPanel);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.HSLRGBPanel);
            app.RedSlider.Limits = [0 255];
            app.GreenSliderLabel = uilabel(app.HSLRGBPanel);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.HSLRGBPanel);
            app.GreenSlider.Limits = [0 255];
            app.BlueSliderLabel = uilabel(app.HSLRGBPanel);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.HSLRGBPanel);
            app.BlueSlider.Limits = [0 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end